classdef PAC_simulations < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        FreqHzEditField           matlab.ui.control.NumericEditField
        FreqHzEditFieldLabel      matlab.ui.control.Label
        SlowwaveparametersLabel   matlab.ui.control.Label
        GoplotbrrrButton          matlab.ui.control.Button
        SlowWavesDropDown         matlab.ui.control.DropDown
        SlowWavesDropDownLabel    matlab.ui.control.Label
        HorizontalShiftKnob       matlab.ui.control.Knob
        HorizontalShiftKnobLabel  matlab.ui.control.Label
        PolarplotPanel            matlab.ui.container.Panel
        PACEditField              matlab.ui.control.EditField
        PACEditFieldLabel         matlab.ui.control.Label
        AmplitudeSlider           matlab.ui.control.Slider
        AmplitudeSliderLabel      matlab.ui.control.Label
        VerticalShiftSlider       matlab.ui.control.Slider
        HorizontalShiftLabel      matlab.ui.control.Label
        UIAxes2                   matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: GoplotbrrrButton, PolarplotPanel
        function GoplotbrrrButtonPushed(app, event)
            
            %% Getting all the values
            Amp = app.AmplitudeSlider.Value;
            horizontal_shift = app.HorizontalShiftKnob.Value;
            vertical_shift = app.VerticalShiftSlider.Value;
            Fc = app.FreqHzEditField.Value; %In Hz
            expression = app.SlowWavesDropDown.Value;

            %% Creating fast and slow synthetic waves

            %%Time specifications:
            Fs = 1000;                   % samples per second
            dt = 1/Fs;                   % seconds per sample
            StopTime = 3;             % seconds
            t = (0:dt:StopTime-dt)';     % seconds


            %%Slow wave

            % parameters : 1) intermediate PAC : vertical_shift = 0.5, horizontal_shift = pi/3,
            % Amp = 0.5, Second slow_waves_egg expression
            % 2) High PAC : vertical_shift = 0 , horizontal_shift = pi/2 , Amp=1, first slow_waves_egg
            % expression
            % 3) Low PAC : vertical_shift = 0, horizontal_shift = pi, Amp = 0.5 , second slow_waves_egg
            % expression

            %%%%%% Creation of slow EGG waves
            if strcmpi(expression,'absolute')
                slow_waves_egg = abs(Amp*cos(2*pi*Fc*t + horizontal_shift))+ vertical_shift; %first slow_waves_egg expression
            else
                slow_waves_egg = Amp*cos(2*pi*Fc*t + horizontal_shift) + vertical_shift; %second slow_waves_egg expression
            end

            slow_sim = (slow_waves_egg);
            %%%%%%%

            %%%%%%%%% Creation of fast EEG waves

            % Slow wave for mixing
            Fc = 1.5;  % hertz
            Amp = 1;
            phaseAng = pi/2;
            slow_waves = Amp*cos(2*pi*Fc*t + phaseAng);

            % Fast wave:
            Fc = 30;  % hertz
            Amp = 1;
            phaseAng = pi/2;
            high_waves = Amp*cos(2*pi*Fc*t + phaseAng);

            fast_sim = (slow_waves.*high_waves);
            %%%%%%%%%%%%

            %% Calculating PAC

            eegH = hilbert(fast_sim);
            eggH = hilbert(slow_sim);

            eeg_amp = abs(eegH);
            egg_phase = angle(eggH);
            eeg_egg_coupled_signal = eeg_amp.*exp(1i*egg_phase);

            %Overall PAC
            pac_numerator = abs(sum(eeg_egg_coupled_signal));
            pac_denominator = sqrt(sum(eeg_amp.*eeg_amp))*sqrt(length(high_waves));
            pac_overall = pac_numerator/pac_denominator;

            %% Plotting and displaying everything
 

            phase = angle(eeg_egg_coupled_signal);
            %magnitude = abs(eeg_egg_coupled_signal);
            angle_PAC = angle(sum(eeg_egg_coupled_signal));
            denom = sqrt((eeg_amp.*eeg_amp));
            magnitude = abs(eeg_egg_coupled_signal)./denom;

            % Plotting the time domain signals
            fig1 = app.UIAxes;
            plot(fig1,t,fast_sim);hold(fig1,"on");
            plot(fig1,t,slow_sim,'r');hold (fig1,'off');
            legend(fig1,'Simulated fast waves','Simulated slow waves')
            
            % Creating figure for power envelope of fast signal and time domain signal
            % of slow signal
            fig2 = app.UIAxes2;
            plot(fig2,slow_sim,'b--'); hold (fig2,'on');
            plot(fig2,eeg_amp,'r'); hold (fig2,'off');
            legend(fig2,'Slow wave','Power envelope of fast wave')
            
            app.PACEditField.Value = num2str(pac_overall);

            % Creating a polar plot
            pax = polaraxes(app.PolarplotPanel);
            nbins = 50;
            polarhistogram(pax,phase,nbins)


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Time domain signals')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes2, 'Time (s)')
            ylabel(app.UIAxes2, 'Amplitude')
            zlabel(app.UIAxes2, 'Z')
            app.HorizontalShiftLabel = uilabel(app.UIFigure);
            app.HorizontalShiftLabel.Text = {'Vertical'; 'Shift'};
            app.VerticalShiftSlider = uislider(app.UIFigure);
            app.VerticalShiftSlider.Limits = [-4 4];
            app.AmplitudeSliderLabel = uilabel(app.UIFigure);
            app.AmplitudeSliderLabel.Text = 'Amplitude';
            app.AmplitudeSlider = uislider(app.UIFigure);
            app.AmplitudeSlider.Limits = [0 4];
            app.AmplitudeSlider.Value = 1;
            app.PACEditFieldLabel = uilabel(app.UIFigure);
            app.PACEditFieldLabel.Text = 'PAC';
            app.PACEditField = uieditfield(app.UIFigure, 'text');
            app.PolarplotPanel = uipanel(app.UIFigure);
            app.PolarplotPanel.Title = 'Polar plot';
            app.PolarplotPanel.ButtonDownFcn = createCallbackFcn(app, @GoplotbrrrButtonPushed, true);
            app.HorizontalShiftKnobLabel = uilabel(app.UIFigure);
            app.HorizontalShiftKnobLabel.Text = {'Horizontal'; 'Shift'};
            app.HorizontalShiftKnob = uiknob(app.UIFigure, 'continuous');
            app.HorizontalShiftKnob.Limits = [-6.28318530717959 6.28318530717959];
            app.HorizontalShiftKnob.Value = 1.5707963267949;
            app.SlowWavesDropDownLabel = uilabel(app.UIFigure);
            app.SlowWavesDropDownLabel.Text = {'Slow'; 'Waves'};
            app.SlowWavesDropDown = uidropdown(app.UIFigure);
            app.SlowWavesDropDown.Items = {'Absolute', 'Continous'};
            app.SlowWavesDropDown.Value = 'Absolute';
            app.GoplotbrrrButton = uibutton(app.UIFigure, 'push');
            app.GoplotbrrrButton.ButtonPushedFcn = createCallbackFcn(app, @GoplotbrrrButtonPushed, true);
            app.GoplotbrrrButton.Text = 'Go plot brrr...';
            app.SlowwaveparametersLabel = uilabel(app.UIFigure);
            app.SlowwaveparametersLabel.Text = 'Slow wave parameters';
            app.FreqHzEditFieldLabel = uilabel(app.UIFigure);
            app.FreqHzEditFieldLabel.Text = 'Freq (Hz)';
            app.FreqHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.FreqHzEditField.Value = 1.5;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PAC_simulations

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end