classdef pac_plots < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        VectordistributionPanel  matlab.ui.container.Panel
        PlotsgobrrrButton        matlab.ui.control.Button
        PACEditField             matlab.ui.control.EditField
        PACEditFieldLabel        matlab.ui.control.Label
        UploadEGGButton          matlab.ui.control.Button
        UploadEEGButton          matlab.ui.control.Button
        UIAxes2                  matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadEEGButton
        function UploadEEGButtonPushed(app, event)
            [file_eeg,path] = uigetfile('*.mat')  %open a .edf file
            app.eeg = file_eeg;
        end

        % Button pushed function: UploadEGGButton
        function UploadEGGButtonPushed(app, event)
            [file_egg, path] = uigetfile('*.mat') %Open a .mat file
            app.egg = file_egg;
        end

        % Button pushed function: PlotsgobrrrButton
        function PlotsgobrrrButtonPushed(app, event)
            
            fast_sim = app.eeg
            slow_sim = app.egg
            %% Calculating PAC

            eegH = hilbert(fast_sim);
            eggH = hilbert(slow_sim);

            eeg_amp = abs(eegH);
            egg_phase = angle(eggH);
            eeg_egg_coupled_signal = eeg_amp.*exp(1i*egg_phase);

            %Overall PAC
            pac_numerator = abs(sum(eeg_egg_coupled_signal));
            pac_denominator = sqrt(sum(eeg_amp.*eeg_amp))*sqrt(length(high_waves));
            pac_overall = pac_numerator/pac_denominator;

             %% Plotting and displaying everything
 

            phase = angle(eeg_egg_coupled_signal);
            %magnitude = abs(eeg_egg_coupled_signal);
            angle_PAC = angle(sum(eeg_egg_coupled_signal));
            denom = sqrt((eeg_amp.*eeg_amp));
            magnitude = abs(eeg_egg_coupled_signal)./denom;

            % Plotting the time domain signals
            fig1 = app.UIAxes;
            plot(fig1,t,fast_sim);hold(fig1,"on");
            plot(fig1,t,slow_sim,'r');hold (fig1,'off');
            legend(fig1,'Simulated fast waves','Simulated slow waves')
            
            % Creating figure for power envelope of fast signal and time domain signal
            % of slow signal
            fig2 = app.UIAxes2;
            plot(fig2,slow_sim,'b--'); hold (fig2,'on');
            plot(fig2,eeg_amp,'r'); hold (fig2,'off');
            legend(fig2,'Slow wave','Power envelope of fast wave')
            
            app.PACEditField.Value = num2str(pac_overall);

            % Creating a polar plot
            pax = polaraxes(app.PolarplotPanel);
            nbins = 50;
            polarhistogram(pax,phase,nbins)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Signal plots')
            xlabel(app.UIAxes, 'Time(s)')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes2, 'Time(s)')
            ylabel(app.UIAxes2, 'Amplitude')
            zlabel(app.UIAxes2, 'Z')
            app.UploadEEGButton = uibutton(app.UIFigure, 'push');
            app.UploadEEGButton.ButtonPushedFcn = createCallbackFcn(app, @UploadEEGButtonPushed, true);
            app.UploadEEGButton.Text = 'Upload EEG ';
            app.UploadEGGButton = uibutton(app.UIFigure, 'push');
            app.UploadEGGButton.ButtonPushedFcn = createCallbackFcn(app, @UploadEGGButtonPushed, true);
            app.UploadEGGButton.Text = 'Upload EGG';
            app.PACEditFieldLabel = uilabel(app.UIFigure);
            app.PACEditFieldLabel.Text = 'PAC';
            app.PACEditField = uieditfield(app.UIFigure, 'text');
            app.PlotsgobrrrButton = uibutton(app.UIFigure, 'push');
            app.PlotsgobrrrButton.ButtonPushedFcn = createCallbackFcn(app, @PlotsgobrrrButtonPushed, true);
            app.PlotsgobrrrButton.Text = 'Plots go brrr...';
            app.VectordistributionPanel = uipanel(app.UIFigure);
            app.VectordistributionPanel.Title = 'Vector distribution';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pac_plots

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end