classdef travelling_wav_simulations < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        PlotGobrrButton      matlab.ui.control.Button
        DisruptionKnob       matlab.ui.control.DiscreteKnob
        DisruptionKnobLabel  matlab.ui.control.Label
        ChooseDropDown       matlab.ui.control.DropDown
        ChooseDropDownLabel  matlab.ui.control.Label
        UIAxes3              matlab.ui.control.UIAxes
        UIAxes2              matlab.ui.control.UIAxes
        UIAxes               matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotGobrrButton
        function PlotGobrrButtonPushed(app, event)
            %% Getting some values
            disr = str2num(app.DisruptionKnob.Value);
            opts = app.ChooseDropDown.Value;

            %% Initialization

            sinephase = linspace(-pi/2,pi/2,50);
            sinefreq = 0.05; %Hz (arbitrary units)
            amplitude = 100;

            %sine wave initialization
            time_points = linspace(0,100,100); %seconds
            num_electrodes = 100;
            clims = [0 500];

            % Setup plot
            fig1 = app.UIAxes;
            imageh = imagesc(fig1,rand(length(time_points)));
            axis(fig1,'xy')

            fig2 = app.UIAxes2;
            amph = imagesc(fig2,rand(length(time_points)));
            axis(fig2,'xy')%, axis(fig2, 'off')
            clim(clims);

            fig3 = app.UIAxes3;
            x = {'Forward', 'Backward'};
            y = [1 1];
            phaseh = bar(fig3,x,y);
            axis(fig3,'xy')
            
            %% Going through all the disruptions

            switch opts
                
                %% For normal propagation
                case 'Normal'

                    for si = 1:length(sinephase)
    
                        %Creating the 2d sine wave
                        %xp = x*cos(sinephase(si)) + y*sin(sinephase(si));
                        %img = sin(2*pi*sinefreq*xp);

                        %Creating the 2d sine wave
                        img = [];
                        for ei = 1:num_electrodes
                            img(ei,:) = amplitude*sin(2*pi*sinefreq*time_points + (ei-1)*sinephase(si));
                        end

                        %Getting the 2d fft
                        imgx = fftshift(fft2(img));
                        powr2 = abs(imgx);
                        %phas2 = angle(imgx);
                        [FW, BW] = propagation_fun(img);

                        %update plots
                        set(imageh, 'CData', img);
                        set(amph, 'CData', powr2);
                        set(phaseh, 'YData', [FW, BW]);
                        %sgtitle('Normal Propagation')
                        pause(0.5)
                    end

                %% Loop to go through in case of layer disruption
                case 'Layer disruption'                   

                    for si = 1:length(sinephase)

                        %Creating the 2d sine wave
                        %xp = x*cos(sinephase(si)) + y*sin(sinephase(si));
                        %img = sin(2*pi*sinefreq*xp);

                        %Creating the 2d sine wave
                        img = [];
                        for ei = 1:num_electrodes/disr
                            img(ei,:) = amplitude*sin(2*pi*sinefreq*time_points + (ei-1)*sinephase(si));
                        end

                        for ji = (num_electrodes/disr)+1:num_electrodes
                            img(ji,:) = amplitude*sin(2*pi*sinefreq*time_points + -1*(ji-1)*sinephase(si));
                        end

                        %Getting the 2d fft
                        imgx = fftshift(fft2(img));
                        powr2 = abs(imgx);
                        %phas2 = angle(imgx);
                        [FW, BW] = propagation_fun(img);

                        %update plots
                        set(imageh, 'CData', img);
                        set(amph, 'CData', powr2);
                        set(phaseh, 'YData', [FW, BW]);
                        %sgtitle('#1 kind of disruption')
                        pause(0.5)
                    end

                case 'Time disruption'   

                    for si = 1:length(sinephase)

                        %Creating the 2d sine wave
                        %xp = x*cos(sinephase(si)) + y*sin(sinephase(si));
                        %img = sin(2*pi*sinefreq*xp);

                        %Creating the 2d sine wave
                        img = [];
                        des_len1 = time_points(1:length(time_points)*(1/disr)); %Desired length for one kind of direction
                        des_len2 = time_points(length(time_points)*(1/disr)+1 : length(time_points));
                        for ei = 1:num_electrodes
                            temp_img1 = amplitude*sin(2*pi*sinefreq*des_len1 + (ei-1)*sinephase(si));
                            temp_img2 = amplitude*sin(2*pi*sinefreq*des_len2 + -0.7*(ei-1)*sinephase(si));
                            img(ei,:) = [temp_img1, temp_img2];
                        end

                        %Getting the 2d fft
                        imgx = fftshift(fft2(img));
                        powr2 = abs(imgx);
                        %phas2 = angle(imgx);
                        [FW, BW] = propagation_fun(img);

                        %update plots
                        set(imageh, 'CData', img);
                        set(amph, 'CData', powr2);
                        set(phaseh, 'YData', [FW, BW]);
                        %sgtitle('#2 kind of disruption')
                        pause(0.5)
                    end           
            end

            %% Function to calculate FW and BW propagation

            function [thX1,thX2]=findingXboundaries(twod_fft,fx)

                %this is just a makiavelic way to find the boundaries of the interesting frequencies (i.e. thX1,thX2) ##to improve
                sumFFT=sum(twod_fft);
                threshold=0.05*max(sumFFT);
                [aa,var]=sort(abs(sumFFT-threshold));
                var=var(1:40); %40 big enough not to cut. ##to improve
                thX1=fx(min(var));
                thX2=fx(max(var));

            end

            function [FW, BW] = propagation_fun(egg_sig)

                twod_fft = abs(fftshift(fft2(egg_sig)));
                a=1; %width pixel to compute the frequency

                [m,n]=size(egg_sig); %numberPixel x and y

                fx=(1/a)*((1:n)-mean(1:n));
                fy=(1/a)*((1:m)-mean(1:m));

                [thX1,thX2]=findingXboundaries(twod_fft,fx);

                upperPart=twod_fft(fy>0,(fx>0 & fx<thX2));
                lowerPart=twod_fft(fy<0,(fx>0 & fx<thX2));

                FW = nanmean(upperPart(:));
                BW = nanmean(lowerPart(:));
            end

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Space domain')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Layers')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '2D FFT')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Propagation strength')
            app.ChooseDropDownLabel = uilabel(app.UIFigure);
            app.ChooseDropDownLabel.Text = 'Choose';
            app.ChooseDropDown = uidropdown(app.UIFigure);
            app.ChooseDropDown.Items = {'Normal', 'Layer disruption', 'Time disruption'};
            app.ChooseDropDown.Value = 'Normal';
            app.DisruptionKnobLabel = uilabel(app.UIFigure);
            app.DisruptionKnobLabel.Text = 'Disruption';
            app.DisruptionKnob = uiknob(app.UIFigure, 'discrete');
            app.DisruptionKnob.Items = {'2', '3', '4', '5', '6'};
            app.DisruptionKnob.Value = '2';
            app.PlotGobrrButton = uibutton(app.UIFigure, 'push');
            app.PlotGobrrButton.ButtonPushedFcn = createCallbackFcn(app, @PlotGobrrButtonPushed, true);
            app.PlotGobrrButton.Text = 'Plot! Go brr..';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = travelling_wav_simulations

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end